classdef fcm_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fcm_gui              matlab.ui.Figure
        push_view            matlab.ui.control.Button
        push_refresh         matlab.ui.control.Button
        push_acrosssubj_gui  matlab.ui.control.Button
        list_beam            matlab.ui.control.ListBox
        list_comcoh          matlab.ui.control.ListBox
        uipanel13            matlab.ui.container.Panel
        push_getcomcoh       matlab.ui.control.Button
        text42               matlab.ui.control.Label
        edit_numjobs         matlab.ui.control.EditField
        push_prepqsub        matlab.ui.control.Button
        push_runlocal        matlab.ui.control.Button
        uipanel12            matlab.ui.container.Panel
        text47               matlab.ui.control.Label
        edit_basestop        matlab.ui.control.EditField
        text46               matlab.ui.control.Label
        edit_basestart       matlab.ui.control.EditField
        text45               matlab.ui.control.Label
        edit_step            matlab.ui.control.EditField
        text41               matlab.ui.control.Label
        text40               matlab.ui.control.Label
        text39               matlab.ui.control.Label
        edit_len             matlab.ui.control.EditField
        text38               matlab.ui.control.Label
        uipanel11            matlab.ui.container.Panel
        push_psd             matlab.ui.control.Button
        text37               matlab.ui.control.Label
        edit_taper           matlab.ui.control.EditField
        check_mtaper         matlab.ui.control.CheckBox
        pop_nfft             matlab.ui.control.DropDown
        text36               matlab.ui.control.Label
        text35               matlab.ui.control.Label
        text34               matlab.ui.control.Label
        edit_hifrq           matlab.ui.control.EditField
        edit_lofrq           matlab.ui.control.EditField
        pop_ftype            matlab.ui.control.DropDown
        text33               matlab.ui.control.Label
        text32               matlab.ui.control.Label
        text31               matlab.ui.control.Label
        push_comcoh2beam     matlab.ui.control.Button
        uipanel3             matlab.ui.container.Panel
        text_datatype        matlab.ui.control.Label
        text_funconn         matlab.ui.control.Label
        text_connections     matlab.ui.control.Label
        text_seed            matlab.ui.control.Label
        push_setconfig       matlab.ui.control.Button
        uipanel7             matlab.ui.container.Panel
        push_selvoxidx       matlab.ui.control.Button
        edit_seedcoord       matlab.ui.control.EditField
        text14               matlab.ui.control.Label
        push_seedcurr        matlab.ui.control.Button
        uipanel2             matlab.ui.container.Panel
        browse_ext           matlab.ui.control.Button
        edit_ext             matlab.ui.control.EditField
        text22               matlab.ui.control.Label
        edit_session         matlab.ui.control.EditField
        edit_filtdata        matlab.ui.control.EditField
        browse_session       matlab.ui.control.Button
        text13               matlab.ui.control.Label
        browse_W             matlab.ui.control.Button
        edit_W               matlab.ui.control.EditField
        text2                matlab.ui.control.Label
        browse_filtdata      matlab.ui.control.Button
        text1                matlab.ui.control.Label
    end

    
    methods (Access = private)
        function fi = fullpath2file(app, fu)
            %------------------
            
            [dum,fi] = fileparts(fu);
        end
        
        function iserr = getparams(app, handles)
            % -------------
            
            
            global conns nuts fuse
            iserr=false;
            
            
            conns.freq.ftype = {'s' 'b'};
            conns.freq.ftype = conns.freq.ftype{get(handles.pop_ftype,'Value')};
            conns.freq.low = get(handles.edit_lofrq,'string');
            conns.freq.high = get(handles.edit_hifrq,'string');
            if any(strcmp(fuse.funconn,{'ampcorr' 'pli'})) && strcmp(fuse.datatype,'3')
                conns.freq.nfft = '';
            else
                conns.freq.nfft = get(handles.pop_nfft,'string');
                conns.freq.nfft = conns.freq.nfft{get(handles.pop_nfft,'value')};
            end
            
            conns.taper.do = get(handles.check_mtaper,'value');
            if conns.taper.do
                conns.taper.width = get(handles.edit_taper,'string');
            end
            
            conns.time.len = get(handles.edit_len,'string');
            conns.time.step= get(handles.edit_step,'string');
            conns.baseline.start = get(handles.edit_basestart,'string');
            conns.baseline.stop  = get(handles.edit_basestop,'string');
            
            if strcmpi(get(handles.push_selvoxidx,'enable'),'on')
                coord = get(handles.edit_seedcoord,'string');
                if ~strcmpi(coord,'ROI')
                    coord = str2num(coord);
                    if isempty(coord)
                        errordlg('Invalid seed voxel coordinate(s).')
                        iserr=true; return
                    end
                    nuts.selvox.ipsi = dsearchn(nuts.voxels,coord);
                elseif ~isfield(nuts,'selvox') || ~isfield(nuts.selvox,'ipsi') || isempty(nuts.selvox.ipsi)
                    errordlg('ROI voxel definition seems to have failed.')
                    iserr=true; return
                end
            end
        end
        
        function periodogram3(app, data, fs, maxfrq)
            % plots average periodgram across all sensors and trials
            
            [len,numchan,numtrial]=size(data);
            nfft=2^nextpow2(2*fs);
            
            P = zeros(nfft/2+1,numchan,numtrial);
            for k=1:numtrial
                for c=1:numchan
                    data(:,c,k)=data(:,c,k)-mean(data(:,c,k));
                    [P(:,c,k),Pf]=periodogram(data(:,c,k),hamming(len),nfft,fs);      % [Pxx,F] = PWELCH(X,WINDOW,NOVERLAP,NFFT,Fs)
                end
            end
            f = find(Pf<=maxfrq);
            P = P(f,:,:);
            Pf= Pf(f);
            
            PP = mean(mean(10.*log10(P),3),2);
            
            figure;
            plot(Pf,PP);
            xlabel('Frequency [Hz]','fontweight','bold')
            ylabel('Power [dB]','fontweight','bold')
            title('Average power spectrogram of all sensors and trials','fontweight','bold')
            set(gca,'xlim',[min(Pf) max(Pf)])
        end
        
        function update_gui(app, handles)
            %----------------------------------
            
            
            global fuse conns
            
            if ~isfield(fuse,'datatype'), fuse.datatype='31'; end
            if ~isfield(fuse,'funconn'),  fuse.funconn='ccohere'; end
            
            if isfield(conns,'files') && isfield(conns.files,'session')
                set(handles.edit_session,'string',conns.files.session)
            end
            
            set(handles.text_seed,'string',['Seed: ' fuse.seed])
            set(handles.text_connections,'string',['Connections: ' fuse.connection])
            set(handles.text_funconn,'string',['Measure: ' fuse.funconn])
            set(handles.text_datatype,'string',['Datatype: ' fuse.datatype])
            if strcmpi(fuse.seed,'Extracerebral')
                set([handles.text22 handles.edit_ext handles.browse_ext],'enable','on')
            else
                set([handles.text22 handles.edit_ext handles.browse_ext],'enable','off')
            end
            if strncmpi(fuse.seed,'Selected',8) || strcmpi(fuse.connectionavg,'Seed')
                set([handles.push_selvoxidx handles.push_seedcurr handles.edit_seedcoord handles.text14],'Enable','on')
            else
                set([handles.push_selvoxidx handles.push_seedcurr handles.edit_seedcoord handles.text14],'Enable','off')
            end
            if ~strcmp(fuse.datatype,'31')
                set([handles.text38 handles.text39 handles.text40 handles.text41 handles.edit_len handles.edit_step],'enable','on')
            else
                set([handles.text38 handles.text39 handles.text40 handles.text41 handles.edit_len handles.edit_step],'enable','off')
            end
            if strcmp(fuse.datatype,'3')
                set([handles.text45 handles.text46 handles.text47 handles.edit_basestart handles.edit_basestop],'enable','on')
            else
                set([handles.text45 handles.text46 handles.text47 handles.edit_basestart handles.edit_basestop],'enable','off')
            end
            if get(handles.pop_ftype,'Value')==2
                set([handles.text37 handles.check_mtaper handles.edit_taper],'enable','off')
                set(handles.check_mtaper,'Value',0)
            else
                set([handles.text37 handles.check_mtaper handles.edit_taper],'enable','on')
            end
            
            update_listcomcoh(app, handles);
            update_listbeam(app, handles);
        end
        
        function update_listbeam(app, handles, preselect)
            %-----------------
            
            
            dir_struct = dir('s_beam*.mat');
            sorted_names = sortrows({dir_struct.name}');
            set(handles.list_beam,'String',sorted_names)
            if nargin>1
                selidx = strmatch(preselect,sorted_names);
                if ~isempty(selidx)
                    set(handles.list_beam,'Value',selidx(1))
                else
                    set(handles.list_beam,'Value',1)
                end
            elseif isempty(get(handles.list_beam,'Value')) && ~isempty(sorted_names)
                set(handles.list_beam,'Value',1)
            elseif get(handles.list_beam,'Value')>length(sorted_names)
                set(handles.list_beam,'Value',1)
            end
        end
        
        function update_listcomcoh(app, handles, preselect)
            %-------------------------
            
            
            tmp = dir('ccohere*.mat'); names = {tmp.name}';
            tmp = dir('nccohere*.mat'); names = [names;{tmp.name}'];
            tmp = dir('glcohere*.mat'); names = [names;{tmp.name}'];
            tmp = dir('comcoh*.mat'); names = [names;{tmp.name}'];
            tmp = dir('ampcorr*.mat'); names = [names;{tmp.name}'];
            tmp = dir('pli*.mat'); names = [names;{tmp.name}']; clear tmp
            sorted_names = sortrows(names);
            set(handles.list_comcoh,'String',sorted_names)
            if nargin>1
                selidx = strmatch(preselect,sorted_names);
                if ~isempty(selidx)
                    set(handles.list_comcoh,'Value',selidx(1))
                else
                    set(handles.list_comcoh,'Value',1)
                end
            elseif isempty(get(handles.list_comcoh,'Value')) && ~isempty(sorted_names)
                set(handles.list_comcoh,'Value',1)
            elseif get(handles.list_comcoh,'Value')>length(sorted_names)
                set(handles.list_comcoh,'Value',1)
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.fcm_gui.CloseRequestFcn = 'clear global conns; closereq';
            app.push_setconfig.ButtonPushedFcn = 'fcm_config_gui; uiwait(gcf); fcm_gui(''update_gui'',guidata(gcbo))';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function fcm_gui_OpeningFcn(app, varargin)
            % --- Executes just before fcm_gui is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.fcm_gui, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to fcm_gui (see VARARGIN)
            
            % Choose default command line output for fcm_gui
            handles.output = hObject;
            % Update handles structure
            guidata(hObject, handles);
            
            global fuse nuts conns
            
            if isempty(fuse)
                fcm_start
            end
            if isfield(nuts,'sessionfile')
                conns.files.session=nuts.sessionfile;
            end
            update_gui(app, handles);
        end

        % Button pushed function: browse_W
        function browse_W_Callback(app, event)
            % --- Executes on button press in browse_W.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to browse_W (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global conns
            [fi,pa]=uigetfile('W_*.mat;weight*.mat;*.is','Select weight matrix file...');
            if isequal(fi,0), return, end
            fu=fullfile(pa,fi);
            conns.files.W = fu;
            set(handles.edit_W,'string',fi)
        end

        % Button pushed function: browse_ext
        function browse_ext_Callback(app, event)
            % --- Executes on button press in browse_ext.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to browse_ext (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global conns
            [fi,pa]=uigetfile('*.mat','Select extracerebral data file...');
            if isequal(fi,0), return, end
            fu=fullfile(pa,fi);
            test=who('-file',fu);
            if ~any(strcmp(test,'ext'))
                errordlg('File does not contain variable "ext".')
                return
            end
            conns.files.ext = fu;
            set(handles.edit_ext,'string',fi)
        end

        % Button pushed function: browse_filtdata
        function browse_filtdata_Callback(app, event)
            % --- Executes on button press in browse_filtdata.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to browse_filtdata (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global conns
            [fi,pa]=uigetfile('filtdata_*.mat','Select filtered data file...');
            if isequal(fi,0), return, end
            fu=fullfile(pa,fi);
            conns.files.filtdata = fu;
            set(handles.edit_filtdata,'string',fi)
        end

        % Button pushed function: browse_session
        function browse_session_Callback(app, event)
            % --- Executes on button press in browse_session.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to browse_session (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global conns nuts
            [fi,pa]=uigetfile('*.mat','Select NUTMEG session file...');
            if isequal(fi,0), return, end
            fu=fullfile(pa,fi);
            conns.files.session = fu;
            nuts = load(conns.files.session,'voxels','voxelsize','coreg');
            set(handles.edit_session,'string',fi)
        end

        % Value changed function: list_beam
        function list_beam_Callback(app, event)
            % --- Executes on selection change in list_beam.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to list_beam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns list_beam contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from list_beam
            update_listbeam(app, handles);
        end

        % Value changed function: list_comcoh
        function list_comcoh_Callback(app, event)
            % --- Executes on selection change in list_comcoh.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to list_comcoh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns list_comcoh contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from list_comcoh
            update_listcomcoh(app, handles);
        end

        % Value changed function: pop_ftype
        function pop_ftype_Callback(app, event)
            % --- Executes on selection change in pop_ftype.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pop_ftype (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns pop_ftype contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from pop_ftype
            global fuse
            if get(hObject,'Value')==2
                set([handles.text37 handles.check_mtaper handles.edit_taper],'enable','off')
                set(handles.check_mtaper,'Value',0)
            else
                set([handles.text37 handles.check_mtaper handles.edit_taper],'enable','on')
            end
        end

        % Button pushed function: push_acrosssubj_gui
        function push_acrosssubj_gui_Callback(app, event)
            % --- Executes on button press in push_acrosssubj_gui.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_acrosssubj_gui (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fcm_across_subject_gui;
            delete(handles.fcm_gui);
        end

        % Button pushed function: push_comcoh2beam
        function push_comcoh2beam_Callback(app, event)
            % --- Executes on button press in push_comcoh2beam.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_comcoh2beam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global conns fuse
            wbar = waitbar(0,'Calculating output volume...');
            
            comcohlist = get(handles.list_comcoh,'string');
            if isempty(comcohlist), return,  end
            conns.files.comcoh = comcohlist{get(handles.list_comcoh,'value')};
            waitbar(.1,wbar);
            if isfield(conns.files,'W') && ( ~isfield(fuse,'roi') || fuse.roi<1 )
                fcm_comcoh2beam(conns.files.comcoh,conns.files.W);
            else
                fcm_comcoh2beam(conns.files.comcoh);
            end
            waitbar(1,wbar)
            close(wbar)
            
            [pa,fi] = fileparts(conns.files.comcoh);
            fi = ['s_beamtf_' fi];
            
            conns.files.beam = fullfile(pa,fi);
            update_listbeam(app, handles,fi);
        end

        % Button pushed function: push_getcomcoh
        function push_getcomcoh_Callback(app, event)
            % --- Executes on button press in push_getcomcoh.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_getcomcoh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global conns fuse
            
            isok=false;
            while ~isok
                [fi, pa] = uiputfile([fuse.funconn '*.mat'], 'Save functional connectivity as...',[fuse.funconn '_.mat']);
                if isequal(fi,0), return, end
                isok=strncmpi(fi,fuse.funconn,length(fuse.funconn));
            end
            conns.files.comcoh = fullfile(pa,fi);
            
            fcm_assemble(conns.files.comcoh);
            save(conns.files.comcoh,'CC');
            
            update_listcomcoh(app, handles,conns.files.comcoh);
            set(handles.push_comcoh2beam,'enable','on')
        end

        % Button pushed function: push_prepqsub
        function push_prepqsub_Callback(app, event)
            % --- Executes on button press in push_prepqsub.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_prepqsub (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global conns fuse
            
            % get parameters
            iserr=getparams(app, handles);
            if iserr, return, end
            if ~isscalar(str2num(conns.freq.low)) || ~isscalar(str2num(conns.freq.high))
                errordlg('Multiple frequency bands are not possible when using the compiled function.'), return
            end
            numjobs = str2num(get(handles.edit_numjobs,'string'));
            if isempty(numjobs), errordlg('Invalid number of cluster jobs.'), return, end
            if numjobs>1 && strcmpi(fuse.seed,'all')
                errordlg('Use one job per subject when calculating all to all connections. Multiple jobs will be slower because each computer runs the same calculations.')
                return
            end
            
            % define connections between voxel pairs
            if strcmpi(fuse.connection,'grid') && ~isfield(fuse,'gridspacing')
                errordlg('You must define grid spacing first (click "Set configuration").')
                return
            end
            if strcmpi(fuse.seed,'extracerebral')
                load(conns.files.ext)
                comps = fcm_conndef(size(ext,2));
            else
                comps = fcm_conndef;
            end
            fcm_preparejobs(comps,numjobs);
            cd ..
            
            % ROI labelling for current subject
            useroi=(isfield(fuse,'roi') && fuse.roi>0);
            if useroi
                [fi,pa]=uigetfile('*.mat','ROI transformation matrices available? (Cancel otherwise)');
                if isequal(fi,0)
                    hw=waitbar(0,'Labelling ROIs... Please wait...');
                    R=fcm_voxel2roi(nuts.voxels,nuts.coreg,fuse.roidef);
                    waitbar(1,hw);
                    delete(hw);
                    [fi,pa]=uiputfile('*.mat','Save ROI transformation matrices as...','roi.mat');
                    if isequal(fi,0), return, end
                    roifile=fullfile(pa,fi);
                    save(roifile,'R');
                    rofime = ['\n' fi];
                else
                    roifile=fullfile(pa,fi);
                    rofime = ['\n' fi];
                end
            else
                rofime = '';
            end
            
            % Inform user
            fun = ['s' fuse.funconn fuse.datatype];
            varargin={conns.files.filtdata conns.files.W comps};
            if ~strcmp(fuse.datatype,'31')
                varargin = [varargin {conns.time.len conns.time.step}];
            end
            varargin = [varargin conns.freq.ftype conns.freq.low conns.freq.high conns.freq.nfft];
            
            if strcmp(fuse.datatype,'3') && ~isempty(conns.baseline.start) && ~isempty(conns.baseline.stop)
                varargin = [varargin {'baseline' conns.baseline.start conns.baseline.stop}];
            end
            if strcmpi(fuse.seed,'extracerebral')
                varargin = [varargin {'ext' fullpath2file(app, conns.files.ext)}];
            end
            if conns.taper.do
                varargin = [varargin {'taper' conns.taper.width}];
            end
            if isfield(fuse,'roi') && fuse.roi>0
                varargin = [varargin {'roi' fullpath2file(app, roifile)}];
            end
            msgbox(sprintf(['Your data is now ready to be processed on a linux cluster. You may need to transfer the following files/directories to the cluster:\n\n' ...
                fullpath2file(app, conns.files.filtdata) '\n' fullpath2file(app, conns.files.W) '\ncomps' rofime '\n\n\nEach job can then be called as follows (all on 1 line):\n\n' ...
                fun ' ' sprintf('%s ',varargin{:}) ...
                '\n\n\nSee qCC' fuse.datatype ' in the "scripts" subfolder for an example.\nOnce done, transfer the results in the folder "' fuse.funconn '" back to the local machine and click "Assemble results from linux cluster"']),'FCM message')
        end

        % Button pushed function: push_psd
        function push_psd_Callback(app, event)
            % --- Executes on button press in push_psd.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_psd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global nuts conns
            
            wbar = waitbar(0,'Calculating sensor power spectrogram...');
            if isfield(nuts,'meg') && isfield(nuts.meg,'data') && ~isempty(nuts.meg.data)
                data=nuts.meg.data; fs=nuts.meg.srate;
                waitbar(.1,wbar);
            else
                if isfield(conns,'files') && isfield(conns.files,'session')
                    tmp=load(conns.files.session,'meg');
                    data=tmp.meg.data; fs=tmp.meg.srate; clear tmp
                    waitbar(.4,wbar);
                else
                    close(wbar)
                    errordlg('Please select NUTMEG session file first.')
                    return
                end
            end
            
            %periodogram3(data,fs,45);
            periodogram3(app, data(:,1:2:end,1:2:end),fs,45);  % Change here if you want to test every channel and trial at expense of speed.
            waitbar(1,wbar);
            close(wbar)
        end

        % Button pushed function: push_refresh
        function push_refresh_Callback(app, event)
            % --- Executes on button press in push_refresh.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_refresh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            update_listcomcoh(app, handles);
            update_listbeam(app, handles);
        end

        % Button pushed function: push_runlocal
        function push_runlocal_Callback(app, event)
            % --- Executes on button press in push_runlocal.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_runlocal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global conns fuse nuts
            
            if ~isfield(nuts,'voxels')
                if ( isfield(conns,'files') && isfield(conns.files,'session') && exist(conns.files.session,'file') )
                    nut_opensession(conns.files.session,true);
                else
                    errordlg('You must open a session file first.'), return
                end
            end
            
            % get parameters
            iserr=getparams(app, handles);
            if iserr, return, end
            if isfield(nuts,'meg')
                nuts.meg.data=[]; %save memory
            end
            
            % ROI labelling for current subject
            useroi=(isfield(fuse,'roi') && fuse.roi>0);
            if useroi
                [fi,pa]=uigetfile('*.mat','ROI transformation matrices available? (Cancel otherwise)');
                if isequal(fi,0)
                    hw=waitbar(0,'Labelling ROIs... Please wait...');
                    R=fcm_voxel2roi(nuts.voxels,nuts.coreg);
                    waitbar(1,hw);
                    delete(hw);
                    [fi,pa]=uiputfile('*.mat','Save ROI transformation matrices as...','roi.mat');
                    if isequal(fi,0), return, end
                    roifile=fullfile(pa,fi);
                    save(roifile,'R')
                else
                    roifile=fullfile(pa,fi);
                    fcm_useroi(roifile);
                end
            end
            
            % define connections between voxel pairs
            if strcmpi(fuse.connection,'grid') && ~isfield(fuse,'gridspacing')
                errordlg('You must define grid spacing first (click "Set configuration").')
                return
            end
            if strcmpi(fuse.seed,'extracerebral')
                load(conns.files.ext)
                comps = fcm_conndef(size(ext,2));
            else
                comps = fcm_conndef;
            end
            
            % Calculate functional connectivity
            varargin={conns.files.filtdata conns.files.W comps};
            if ~strcmp(fuse.datatype,'31')
                varargin = [varargin {conns.time.len conns.time.step}];
            end
            varargin = [varargin conns.freq.ftype str2num(conns.freq.low) str2num(conns.freq.high) conns.freq.nfft];
            
            if strcmp(fuse.datatype,'3') && ~isempty(conns.baseline.start) && ~isempty(conns.baseline.stop)
                varargin = [varargin {'baseline' conns.baseline.start conns.baseline.stop}];
            end
            if strcmpi(fuse.seed,'extracerebral')
                varargin = [varargin {'ext' ext}];
            end
            if conns.taper.do
                varargin = [varargin {'taper' conns.taper.width}];
            end
            if useroi
                varargin = [varargin {'roi' roifile}];
            end
            % ttt=clock;
            % varargin = [varargin {'savevirt' sprintf('rloc_%d%d%d_%dh%d',ttt(1:5))}];  clear ttt
            
            % output filename
            isok=false;
            while ~isok
                [fi, pa] = uiputfile([fuse.funconn '*.mat'], 'Save functional connectivity as...',[fuse.funconn '_.mat']);
                if isequal(fi,0), return, end
                isok=strncmpi(fi,fuse.funconn,length(fuse.funconn));
            end
            conns.files.comcoh = fullfile(pa,fi);
            
            % Calc
            fcm_sourceconnectivity(conns.files.comcoh,varargin{:});
            
            update_listcomcoh(app, handles,fi);
        end

        % Button pushed function: push_seedcurr
        function push_seedcurr_Callback(app, event)
            % --- Executes on button press in push_seedcurr.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_seedcurr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global st nuts conns
            
            if isempty(findobj('tag','nutmegfig'))
                errordlg('Open NUTMEG main GUI first.'), return
            end
            if ~isfield(st,'megp')
                errordlg('NUTMEG has not been properly initialized. Start it over and try again.'), return
            end
            if ~isfield(nuts,'voxels')
                if issubfield(conns,'files.session')
                    nuts=load(conns.files.session,'voxels');
                    if ~isfield(nuts,'voxels')
                        errordlg('Your session file seems to be invalid or saved in an old format.'), return
                    end
                else
                    errordlg('Please select NUTMEG session file first.')
                    return
                end
            end
            coord = str2num(get(st.megp,'string'));
            nuts.selvox.ipsi = dsearchn(nuts.voxels,coord);
            set(handles.edit_seedcoord,'string',sprintf('%1.2f %1.2f %1.2f',nuts.voxels(nuts.selvox.ipsi,:)));
        end

        % Button pushed function: push_selvoxidx
        function push_selvoxidx_Callback(app, event)
            % --- Executes on button press in push_selvoxidx.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_selvoxidx (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global fuse
            
            if ( isfield(fuse,'roi') && fuse.roi>0 );
                roi = fcm_roiselect_gui;
            else
                sel=menu('How do you want to define the ROI?','Paint polygons','Select anatomical ROI','Load MATLAB file with ROI voxel coordinates','Cancel');
                switch sel
                    case 1
                        roi = fcm_paintroi;
                    case 2
                        roi = fcm_anatroi('gui');
                    case 3
                        [fi,pa]=uigetfile('*.mat','Load ROI voxel coordinates...');
                        if isequal(fi,0), return, end
                        roi=load(fullfile(pa,fi));
                        if ~any(isfield(roi,{'voxels' 'MEGvoxels' 'MNIvoxels' 'MRIvoxels'}))
                            errordlg('File must contain one of the variables "MRIvoxels", "MNIvoxels" or "MEGvoxels".')
                            return
                        end
                        % force display of custom ROIs to check location
                        global st nuts
                        spm_orthviews('rmblobs',1)
                        if get(hObject,'Value')
                            if isempty(findobj('tag','nutmegfig'))
                                if isempty(nuts)
                                    error('You must load session file first.')
                                end
                                nutmeg(nuts);
                            end
                            vmm = nut_mni2mri(double(roi.MNIvoxels));
                            XYZ= nut_mm2voxels(vmm);
                            Z=ones(size(XYZ,1),1);
                            figure(st.fig)
                            spm_orthviews('addcolouredblobs',1,XYZ.',Z,st.vols{1}.mat,[1 0 0])
                            spm_orthviews('reposition',mean(vmm))
                        end
                    case 4
                        return
                end
            end
            if isempty(roi), return, end
            
            fcm_roiidx(roi);
            
            figure(handles.fcm_gui)
            set(handles.edit_seedcoord,'string','ROI');
        end

        % Button pushed function: push_view
        function push_view_Callback(app, event)
            % --- Executes on button press in push_view.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_view (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global conns
            beamlist = get(handles.list_beam,'string');
            if ~isempty(beamlist)
                conns.files.beam = beamlist{get(handles.list_beam,'value')};
                tv(conns.files.beam)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fcm_gui = uifigure('Visible', 'off');
            colormap(app.fcm_gui, 'parula');
            app.fcm_gui.Name = 'fcm_gui';
            app.fcm_gui.Tag = 'fcm_gui';
            app.uipanel2 = uipanel(app.fcm_gui);
            app.uipanel2.Title = 'Files';
            app.uipanel2.Tag = 'uipanel2';
            app.text1 = uilabel(app.uipanel2);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Filtered Data';
            app.browse_filtdata = uibutton(app.uipanel2, 'push');
            app.browse_filtdata.ButtonPushedFcn = createCallbackFcn(app, @browse_filtdata_Callback, true);
            app.browse_filtdata.Tag = 'browse_filtdata';
            app.browse_filtdata.Text = 'Browse';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Weight Matrix';
            app.edit_W = uieditfield(app.uipanel2, 'text');
            app.edit_W.Tag = 'edit_W';
            app.edit_W.Value = '(none)';
            app.browse_W = uibutton(app.uipanel2, 'push');
            app.browse_W.ButtonPushedFcn = createCallbackFcn(app, @browse_W_Callback, true);
            app.browse_W.Tag = 'browse_W';
            app.browse_W.Text = 'Browse';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Session';
            app.browse_session = uibutton(app.uipanel2, 'push');
            app.browse_session.ButtonPushedFcn = createCallbackFcn(app, @browse_session_Callback, true);
            app.browse_session.Tag = 'browse_session';
            app.browse_session.Text = 'Browse';
            app.edit_filtdata = uieditfield(app.uipanel2, 'text');
            app.edit_filtdata.Tag = 'edit_filtdata';
            app.edit_filtdata.Value = '(none)';
            app.edit_session = uieditfield(app.uipanel2, 'text');
            app.edit_session.Tag = 'edit_session';
            app.edit_session.Value = '(none)';
            app.text22 = uilabel(app.uipanel2);
            app.text22.Tag = 'text22';
            app.text22.Enable = 'off';
            app.text22.Text = 'Extra Channel Data';
            app.edit_ext = uieditfield(app.uipanel2, 'text');
            app.edit_ext.Tag = 'edit_ext';
            app.edit_ext.Enable = 'off';
            app.edit_ext.Value = '(none)';
            app.browse_ext = uibutton(app.uipanel2, 'push');
            app.browse_ext.ButtonPushedFcn = createCallbackFcn(app, @browse_ext_Callback, true);
            app.browse_ext.Tag = 'browse_ext';
            app.browse_ext.Enable = 'off';
            app.browse_ext.Text = 'Browse';
            app.uipanel7 = uipanel(app.fcm_gui);
            app.uipanel7.Title = 'Select Seed Voxel(s)';
            app.uipanel7.Tag = 'uipanel7';
            app.push_seedcurr = uibutton(app.uipanel7, 'push');
            app.push_seedcurr.ButtonPushedFcn = createCallbackFcn(app, @push_seedcurr_Callback, true);
            app.push_seedcurr.Tag = 'push_seedcurr';
            app.push_seedcurr.Enable = 'off';
            app.push_seedcurr.Text = 'Seed Current Voxel';
            app.text14 = uilabel(app.uipanel7);
            app.text14.Tag = 'text14';
            app.text14.Enable = 'off';
            app.text14.Text = 'Seed coordinate';
            app.edit_seedcoord = uieditfield(app.uipanel7, 'text');
            app.edit_seedcoord.Tag = 'edit_seedcoord';
            app.edit_seedcoord.Enable = 'off';
            app.edit_seedcoord.Value = '[x y z]';
            app.push_selvoxidx = uibutton(app.uipanel7, 'push');
            app.push_selvoxidx.ButtonPushedFcn = createCallbackFcn(app, @push_selvoxidx_Callback, true);
            app.push_selvoxidx.Tag = 'push_selvoxidx';
            app.push_selvoxidx.Enable = 'off';
            app.push_selvoxidx.Text = 'Define Region';
            app.uipanel3 = uipanel(app.fcm_gui);
            app.uipanel3.Title = 'Configuration';
            app.uipanel3.Tag = 'uipanel3';
            app.push_setconfig = uibutton(app.uipanel3, 'push');
            app.push_setconfig.Tag = 'push_setconfig';
            app.push_setconfig.Text = 'Set Configuration';
            app.text_seed = uilabel(app.uipanel3);
            app.text_seed.Tag = 'text_seed';
            app.text_seed.Text = 'Seed:                   ';
            app.text_connections = uilabel(app.uipanel3);
            app.text_connections.Tag = 'text_connections';
            app.text_connections.Text = 'Connections:                                  ';
            app.text_funconn = uilabel(app.uipanel3);
            app.text_funconn.Tag = 'text_funconn';
            app.text_funconn.Text = 'Measure:                   ';
            app.text_datatype = uilabel(app.uipanel3);
            app.text_datatype.Tag = 'text_datatype';
            app.text_datatype.Text = 'Datatype:                                  ';
            app.push_comcoh2beam = uibutton(app.fcm_gui, 'push');
            app.push_comcoh2beam.ButtonPushedFcn = createCallbackFcn(app, @push_comcoh2beam_Callback, true);
            app.push_comcoh2beam.Tag = 'push_comcoh2beam';
            app.push_comcoh2beam.Text = 'Calc Output Volume';
            app.uipanel11 = uipanel(app.fcm_gui);
            app.uipanel11.Title = 'Frequency';
            app.uipanel11.Tag = 'uipanel11';
            app.text31 = uilabel(app.uipanel11);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Low Freq Cutoff';
            app.text32 = uilabel(app.uipanel11);
            app.text32.Tag = 'text32';
            app.text32.Text = 'High Freq Cutoff';
            app.text33 = uilabel(app.uipanel11);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Analyze Frequency';
            app.pop_ftype = uidropdown(app.uipanel11);
            app.pop_ftype.Items = {'Spectrum', 'Band'};
            app.pop_ftype.ValueChangedFcn = createCallbackFcn(app, @pop_ftype_Callback, true);
            app.pop_ftype.Tag = 'pop_ftype';
            app.pop_ftype.Value = 'Spectrum';
            app.edit_lofrq = uieditfield(app.uipanel11, 'text');
            app.edit_lofrq.Tag = 'edit_lofrq';
            app.edit_hifrq = uieditfield(app.uipanel11, 'text');
            app.edit_hifrq.Tag = 'edit_hifrq';
            app.text34 = uilabel(app.uipanel11);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Hz';
            app.text35 = uilabel(app.uipanel11);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Hz';
            app.text36 = uilabel(app.uipanel11);
            app.text36.Tag = 'text36';
            app.text36.Text = '# FFT Freq Bins';
            app.pop_nfft = uidropdown(app.uipanel11);
            app.pop_nfft.Items = {'32', '64', '128', '256', '512', '1024', '2048', '4096'};
            app.pop_nfft.Tag = 'pop_nfft';
            app.pop_nfft.Value = '512';
            app.check_mtaper = uicheckbox(app.uipanel11);
            app.check_mtaper.Tag = 'check_mtaper';
            app.check_mtaper.Text = 'Multi Tapering:';
            app.edit_taper = uieditfield(app.uipanel11, 'text');
            app.edit_taper.Tag = 'edit_taper';
            app.text37 = uilabel(app.uipanel11);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Hz';
            app.push_psd = uibutton(app.uipanel11, 'push');
            app.push_psd.ButtonPushedFcn = createCallbackFcn(app, @push_psd_Callback, true);
            app.push_psd.Tag = 'push_psd';
            app.push_psd.Text = 'PSD';
            app.uipanel12 = uipanel(app.fcm_gui);
            app.uipanel12.Title = 'Time Windows';
            app.uipanel12.Tag = 'uipanel12';
            app.text38 = uilabel(app.uipanel12);
            app.text38.Tag = 'text38';
            app.text38.Enable = 'off';
            app.text38.Text = 'Length';
            app.edit_len = uieditfield(app.uipanel12, 'text');
            app.edit_len.Tag = 'edit_len';
            app.edit_len.Enable = 'off';
            app.text39 = uilabel(app.uipanel12);
            app.text39.Tag = 'text39';
            app.text39.Enable = 'off';
            app.text39.Text = 'Step/Overlap';
            app.text40 = uilabel(app.uipanel12);
            app.text40.Tag = 'text40';
            app.text40.Enable = 'off';
            app.text40.Text = 's';
            app.text41 = uilabel(app.uipanel12);
            app.text41.Tag = 'text41';
            app.text41.Enable = 'off';
            app.text41.Text = 's';
            app.edit_step = uieditfield(app.uipanel12, 'text');
            app.edit_step.Tag = 'edit_step';
            app.edit_step.Enable = 'off';
            app.text45 = uilabel(app.uipanel12);
            app.text45.Tag = 'text45';
            app.text45.Enable = 'off';
            app.text45.Text = 'Baseline';
            app.edit_basestart = uieditfield(app.uipanel12, 'text');
            app.edit_basestart.Tag = 'edit_basestart';
            app.edit_basestart.Enable = 'off';
            app.text46 = uilabel(app.uipanel12);
            app.text46.Tag = 'text46';
            app.text46.Enable = 'off';
            app.text46.Text = 's';
            app.edit_basestop = uieditfield(app.uipanel12, 'text');
            app.edit_basestop.Tag = 'edit_basestop';
            app.edit_basestop.Enable = 'off';
            app.text47 = uilabel(app.uipanel12);
            app.text47.Tag = 'text47';
            app.text47.Enable = 'off';
            app.text47.Text = '-';
            app.uipanel13 = uipanel(app.fcm_gui);
            app.uipanel13.Title = 'Calculate Source Connectivity';
            app.uipanel13.Tag = 'uipanel13';
            app.push_runlocal = uibutton(app.uipanel13, 'push');
            app.push_runlocal.ButtonPushedFcn = createCallbackFcn(app, @push_runlocal_Callback, true);
            app.push_runlocal.Tag = 'push_runlocal';
            app.push_runlocal.Text = 'Run Local';
            app.push_prepqsub = uibutton(app.uipanel13, 'push');
            app.push_prepqsub.ButtonPushedFcn = createCallbackFcn(app, @push_prepqsub_Callback, true);
            app.push_prepqsub.Tag = 'push_prepqsub';
            app.push_prepqsub.Text = 'Prepare for Linux Cluster';
            app.edit_numjobs = uieditfield(app.uipanel13, 'text');
            app.edit_numjobs.Tag = 'edit_numjobs';
            app.edit_numjobs.Value = '10';
            app.text42 = uilabel(app.uipanel13);
            app.text42.Tag = 'text42';
            app.text42.Text = 'jobs';
            app.push_getcomcoh = uibutton(app.uipanel13, 'push');
            app.push_getcomcoh.ButtonPushedFcn = createCallbackFcn(app, @push_getcomcoh_Callback, true);
            app.push_getcomcoh.Tag = 'push_getcomcoh';
            app.push_getcomcoh.Text = 'Assemble Results from Linux Cluster';
            app.list_comcoh = uilistbox(app.fcm_gui);
            app.list_comcoh.Items = {};
            app.list_comcoh.ValueChangedFcn = createCallbackFcn(app, @list_comcoh_Callback, true);
            app.list_comcoh.Tag = 'list_comcoh';
            app.list_comcoh.Value = {};
            app.list_beam = uilistbox(app.fcm_gui);
            app.list_beam.Items = {};
            app.list_beam.ValueChangedFcn = createCallbackFcn(app, @list_beam_Callback, true);
            app.list_beam.Tag = 'list_beam';
            app.list_beam.Value = {};
            app.push_acrosssubj_gui = uibutton(app.fcm_gui, 'push');
            app.push_acrosssubj_gui.ButtonPushedFcn = createCallbackFcn(app, @push_acrosssubj_gui_Callback, true);
            app.push_acrosssubj_gui.Tag = 'push_acrosssubj_gui';
            app.push_acrosssubj_gui.Text = 'Across Subject Stats';
            app.push_refresh = uibutton(app.fcm_gui, 'push');
            app.push_refresh.ButtonPushedFcn = createCallbackFcn(app, @push_refresh_Callback, true);
            app.push_refresh.Tag = 'push_refresh';
            app.push_refresh.Text = 'Ref';
            app.push_view = uibutton(app.fcm_gui, 'push');
            app.push_view.ButtonPushedFcn = createCallbackFcn(app, @push_view_Callback, true);
            app.push_view.Tag = 'push_view';
            app.push_view.Text = 'View';
            % Show the figure after all components are created
            app.fcm_gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fcm_gui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.fcm_gui)

                % Execute the startup function
                runStartupFcn(app, @(app)fcm_gui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.fcm_gui)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fcm_gui)
        end
    end
end