classdef nut_CoregistrationTool_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nut_CoregistrationTool       matlab.ui.Figure
        nut_renderedbrain_nametext   matlab.ui.control.Label
        nut_renderedbrain            matlab.ui.control.Button
        nut_normimage_nametext       matlab.ui.control.Label
        nut_normMRI                  matlab.ui.control.Button
        nut_showlsc                  matlab.ui.control.Button
        nut_lsc_text                 matlab.ui.control.Label
        nut_lsc                      matlab.ui.control.Button
        nut_coreg_firstpass          matlab.ui.control.Button
        text6                        matlab.ui.control.Label
        nut_done_coreg               matlab.ui.control.Button
        nut_ImageNameText            matlab.ui.control.Label
        nut_orientation_menu         matlab.ui.control.DropDown
        nut_adjust_coreg             matlab.ui.control.Button
        nut_edit_fiducials_3d        matlab.ui.control.Button
        nut_show_fiducials_3d        matlab.ui.control.Button
        nut_verify_coregistry_in_3d  matlab.ui.control.Button
        nut_genmesh                  matlab.ui.control.Button
        nut_load_image               matlab.ui.control.Button
        text_orientation             matlab.ui.control.Label
        nut_loadHeadshape            matlab.ui.control.Button
        nut_load_fiducials           matlab.ui.control.Button
        nut_shownose                 matlab.ui.control.Button
        nut_showright                matlab.ui.control.Button
        nut_showleft                 matlab.ui.control.Button
        nut_nose_text                matlab.ui.control.Label
        nut_right_text               matlab.ui.control.Label
        nut_left_text                matlab.ui.control.Label
        nut_saveFiducials            matlab.ui.control.Button
        nut_nose                     matlab.ui.control.Button
        nut_right                    matlab.ui.control.Button
        nut_left                     matlab.ui.control.Button
    end

    
    methods (Access = private)
        function nut_orientation_menu_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to nut_orientation_menu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.nut_left.ButtonPushedFcn = 'nut_fiducial_callback';
            app.nut_right.ButtonPushedFcn = 'nut_fiducial_callback';
            app.nut_nose.ButtonPushedFcn = 'nut_fiducial_callback';
            app.nut_saveFiducials.ButtonPushedFcn = 'nut_fiducial_callback';
            app.nut_showleft.ButtonPushedFcn = 'nut_fiducial_callback';
            app.nut_showright.ButtonPushedFcn = 'nut_fiducial_callback';
            app.nut_shownose.ButtonPushedFcn = 'nut_fiducial_callback';
            app.nut_load_fiducials.ButtonPushedFcn = 'nut_fiducial_callback';
            app.nut_loadHeadshape.ButtonPushedFcn = 'nut_importheadshape';
            app.nut_load_image.ButtonPushedFcn = 'nut_load_image';
            app.nut_genmesh.ButtonPushedFcn = 'nut_GenMesh_tool';
            app.nut_verify_coregistry_in_3d.ButtonPushedFcn = 'nut_verify_coregistry_in_3d';
            app.nut_show_fiducials_3d.ButtonPushedFcn = 'nut_show_fiducials';
            app.nut_edit_fiducials_3d.ButtonPushedFcn = 'nut_edit_fiducials';
            app.nut_adjust_coreg.ButtonPushedFcn = 'nut_adjust_coregistry';
            app.nut_coreg_firstpass.ButtonPushedFcn = 'nut_coreg_firstpass';
            app.nut_lsc.ButtonPushedFcn = 'nut_fiducial_callback';
            app.nut_showlsc.ButtonPushedFcn = 'nut_fiducial_callback';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function nut_CoregistrationTool_OpeningFcn(app, varargin)
            % --- Executes just before nut_CoregistrationTool is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.nut_CoregistrationTool, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to nut_CoregistrationTool (see VARARGIN)
            
            % Choose default command line output for nut_CoregistrationTool
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes nut_CoregistrationTool wait for user response (see UIRESUME)
            % uiwait(handles.nut_CoregistrationTool);
            
            global nuts screws
            
            % fill in previously selected information, if available
            if isfield(nuts.coreg, 'mripath')
                if length(nuts.coreg.mripath)>35
                    imagestring=['...' nuts.coreg.mripath(end-30:end)];
                    set(handles.nut_ImageNameText,'String',imagestring);
                else
                    set(handles.nut_ImageNameText,'String',nuts.coreg.mripath);
                end
            end
            if isfield(nuts.coreg,'norm_mripath')
                if length(nuts.coreg.norm_mripath)>40
                    imagestring=['...' nuts.coreg.norm_mripath(end-35:end)];
                    set(handles.nut_normimage_nametext,'String',imagestring);
                else
                    set(handles.nut_normimage_nametext,'String',nuts.coreg.norm_mripath);
                end
            end
            if isfield(nuts.coreg,'brainrender_path')
                if length(nuts.coreg.brainrender_path)>40
                    imagestring=['...' nuts.coreg.brainrender_path(end-35:end)];
                    set(handles.nut_renderedbrain_nametext,'String',imagestring);
                else
                    set(handles.nut_renderedbrain_nametext,'String',nuts.coreg.brainrender_path);
                end
            end
            
            % if(isfield(nuts.coreg,'fiducials_mri_mm'))
            %     set(handles.nut_left_text,'String',['MRI: ' num2str(nuts.coreg.fiducials_mri_mm(1,:),'%.1f ')]);
            %     set(handles.nut_right_text,'String',['MRI: ' num2str(nuts.coreg.fiducials_mri_mm(2,:),'%.1f ')]);
            %     set(handles.nut_nose_text,'String',['MRI: ' num2str(nuts.coreg.fiducials_mri_mm(3,:),'%.1f ')]);
            % end
            
            screws.coreg.handles = handles;
            nut_coreg_enabler
        end

        % Button pushed function: nut_done_coreg
        function nut_done_coreg_Callback(app, event)
            % --- Executes on button press in nut_done_coreg.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nut_done_coreg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global nuts
            
            % This happens when template MR was warped to individual sensorCoords.
            % Need to make sure the new meg2mri_tfm does not produce shift
            if isfield(nuts.coreg,'sensorCoord')
                nuts.meg.sensorCoord = nut_mri2meg(nuts.coreg.sensorCoord);
            end
            
            clear global screws
            closereq;
        end

        % Button pushed function: nut_normMRI
        function nut_normMRI_Callback(app, event)
            % --- Executes on button press in nut_normMRI.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nut_normMRI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global screws rivets nuts
            if get(screws.coreg.handles.nut_orientation_menu,'Value')==2
                errordlg('you must use a neurological MRI for the whole spatial normalization thing to work here');
            end
            [mrifile, norm_mripath]=uigetfile('w*.img;w*.nii','Please Select Normed MRI...');
            if isequal(mrifile,0)|isequal(norm_mripath,0)
                return;
            end
            
            h=waitbar(0.2,'Loading normalized image volume...');
            
            nuts.coreg.norm_mripath = fullfile(norm_mripath,mrifile);
            if length(nuts.coreg.norm_mripath)>40
                imagestring=['...' nuts.coreg.norm_mripath(end-35:end)];
                set(findobj('Tag','nut_normimage_nametext'),'Text',imagestring);
            else
                set(findobj('Tag','nut_normimage_nametext'),'Text',nuts.coreg.norm_mripath);
            end
            snmat=[norm_mripath mrifile(2:end-4) '_sn.mat'];
            yimg=[norm_mripath 'y_' mrifile(2:end)];
            if ~exist([yimg(1:end-4) '.nii'],'file') && ~exist([yimg(1:end-4) '.img'],'file')
                if strncmp(spm('ver'),'SPM8',4)
                    cwd=pwd;
                    cd(norm_mripath); %ugly hack because SPM8 wants to write files to 'pwd'
                    spm_jobman('initcfg');
            
                    % generate deformation field (MNI -> individual MRI)
                    matlabbatch{1}.spm.util.defs.comp{1}.sn2def.matname = {snmat};
                    matlabbatch{1}.spm.util.defs.comp{1}.sn2def.vox = NaN(1,3);
                    matlabbatch{1}.spm.util.defs.comp{1}.sn2def.bb = NaN(2,3);
                    matlabbatch{1}.spm.util.defs.ofname = mrifile(2:(end-4));
                    matlabbatch{1}.spm.util.defs.fnames = '';
                    matlabbatch{1}.spm.util.defs.interp = 1;
            
                    % generate inverse deformation field (individual MRI -> MNI)
                    matlabbatch{2}.spm.util.defs.comp{1}.inv.comp{1}.sn2def.matname = {snmat};
                    matlabbatch{2}.spm.util.defs.comp{1}.inv.comp{1}.sn2def.vox = NaN(1,3);
                    matlabbatch{2}.spm.util.defs.comp{1}.inv.comp{1}.sn2def.bb = NaN(2,3);
                    matlabbatch{2}.spm.util.defs.comp{1}.inv.space = {nuts.coreg.norm_mripath};
                    matlabbatch{2}.spm.util.defs.ofname = ['i' mrifile(2:(end-4))];
                    matlabbatch{2}.spm.util.defs.fnames = '';
                    matlabbatch{2}.spm.util.defs.interp = 1;
            
                    spm_jobman('run',matlabbatch);
                    cd(cwd);
                elseif strncmp(spm('ver'),'SPM12',4)
                    cwd=pwd;
                    cd(norm_mripath); %ugly hack because SPM8 wants to write files to 'pwd'
                    spm_jobman('initcfg');
            
                    % generate deformation field (MNI -> individual MRI)
                    matlabbatch{1}.spm.util.defs.comp{1}.sn2def.matname = {snmat};
                    matlabbatch{1}.spm.util.defs.comp{1}.sn2def.vox = NaN(1,3);
                    matlabbatch{1}.spm.util.defs.comp{1}.sn2def.bb = NaN(2,3);
                    matlabbatch{1}.spm.util.defs.ofname = mrifile(2:(end-4));
                    matlabbatch{1}.spm.util.defs.fnames = '';
                    matlabbatch{1}.spm.util.defs.interp = 1;
            
                    % generate inverse deformation field (individual MRI -> MNI)
                    matlabbatch{2}.spm.util.defs.comp{1}.inv.comp{1}.sn2def.matname = {snmat};
                    matlabbatch{2}.spm.util.defs.comp{1}.inv.comp{1}.sn2def.vox = NaN(1,3);
                    matlabbatch{2}.spm.util.defs.comp{1}.inv.comp{1}.sn2def.bb = NaN(2,3);
                    matlabbatch{2}.spm.util.defs.comp{1}.inv.space = {nuts.coreg.norm_mripath};
                    matlabbatch{2}.spm.util.defs.ofname = ['i' mrifile(2:(end-4))];
                    matlabbatch{2}.spm.util.defs.fnames = '';
                    matlabbatch{2}.spm.util.defs.interp = 1;
            
                    spm_jobman('run',matlabbatch);
                    cd(cwd);
                elseif(strcmp(spm('ver'),'SPM2'))
                    nut_spm_sn2def('def',snmat);  % you'll need this later for beam coord on-the-fly transform
                    iyimg=[norm_mripath 'iy_' mrifile(2:end)];
            
                    if exist(iyimg,'file')~=2
                        inputimg=[norm_mripath mrifile(2:end)];
                        nut_spm_invdef(yimg,inputimg);
                    end
                elseif(strcmp(spm('ver'),'SPM2'))
                    nut_spm_sn2def('def',snmat);  % you'll need this later for beam coord on-the-fly transform
                    iyimg=[norm_mripath 'iy_' mrifile(2:end)];
            
                    if exist(iyimg,'file')~=2
                        inputimg=[norm_mripath mrifile(2:end)];
                        nut_spm_invdef(yimg,inputimg);
                    end
                else
                    errordlg(['NUTMEG is not compatible with ' spm('ver') '. Please place SPM2 or SPM8 in your path, which may be downloaded from http://www.fil.ion.ucl.ac.uk/spm'])
                    return
                end
                waitbar(0.6,h);
            end
            
            if(~isfield(rivets,'TalDB'))
                %load('ihb_DataBase.cdb','-mat'); % load MNI labels
                %rivets.MNIdb = MNIdb;
                %[meshx,meshy,meshz]=ndgrid(MNIdb.minX:MNIdb.voxX:MNIdb.maxX,MNIdb.minY:MNIdb.voxY:MNIdb.maxY,MNIdb.minZ:MNIdb.voxZ:MNIdb.maxZ);
                %rivets.MNIdb.coords = [meshx(:) meshy(:) meshz(:)];
            
                %rivets.MNIdm = nut_loadMNI;
                rivets.TalDB = load('talairachDB');
            end
            if(~isfield(rivets,'MNIdb'))
                load('ihb_DataBase.cdb','-mat'); % load MNI labels
                rivets.MNIdb = MNIdb;
                [meshx,meshy,meshz]=ndgrid(MNIdb.minX:MNIdb.voxX:MNIdb.maxX,MNIdb.minY:MNIdb.voxY:MNIdb.maxY,MNIdb.minZ:MNIdb.voxZ:MNIdb.maxZ);
                rivets.MNIdb.coords = [meshx(:) meshy(:) meshz(:)];
            end
            waitbar(1,h);
            close(h);
        end

        % Value changed function: nut_orientation_menu
        function nut_orientation_menu_Callback(app, event)
            % --- Executes on selection change in nut_orientation_menu.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nut_orientation_menu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns nut_orientation_menu contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from nut_orientation_menu
            global nuts
            nuts.coreg.orientation=get(hObject,'Value');
            nut_coreg_enabler;
        end

        % Button pushed function: nut_renderedbrain
        function nut_renderedbrain_Callback(app, event)
            % --- Executes on button press in nut_renderedbrain.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nut_renderedbrain (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global screws nuts
            if get(screws.coreg.handles.nut_orientation_menu,'Value')==2
                errordlg('you must use a neurological MRI for the whole rendered brain bit to work here');
            end
            [brainrender_file, brainrender_path]=uigetfile('render_*.mat','Please Select SPM-Rendered Brain...');
            if isequal(brainrender_file,0)|isequal(brainrender_path,0)
                return;
            end
            
            nuts.coreg.brainrender_path = fullfile(brainrender_path,brainrender_file);
            if length(nuts.coreg.brainrender_path)>40
                imagestring=['...' nuts.coreg.brainrender_path(end-35:end)];
                set(findobj('Tag','nut_renderedbrain_nametext'),'String',imagestring);
            else
                set(findobj('Tag','nut_renderedbrain_nametext'),'String',nuts.coreg.brainrender_mripath);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nut_CoregistrationTool = uifigure('Visible', 'off');
            colormap(app.nut_CoregistrationTool, 'parula');
            app.nut_CoregistrationTool.Name = 'Coregistration Tool';
            app.nut_CoregistrationTool.Tag = 'nut_CoregistrationTool';
            app.nut_left = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_left.Tag = 'nut_left';
            app.nut_left.Text = 'set left PA';
            app.nut_right = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_right.Tag = 'nut_right';
            app.nut_right.Text = 'set right PA';
            app.nut_nose = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_nose.Tag = 'nut_nose';
            app.nut_nose.Text = 'set nasion';
            app.nut_saveFiducials = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_saveFiducials.Tag = 'nut_saveFiducials';
            app.nut_saveFiducials.Text = 'Export fiducials...';
            app.nut_left_text = uilabel(app.nut_CoregistrationTool);
            app.nut_left_text.Tag = 'nut_left_text';
            app.nut_left_text.Text = '';
            app.nut_right_text = uilabel(app.nut_CoregistrationTool);
            app.nut_right_text.Tag = 'nut_right_text';
            app.nut_right_text.Text = '';
            app.nut_nose_text = uilabel(app.nut_CoregistrationTool);
            app.nut_nose_text.Tag = 'nut_nose_text';
            app.nut_nose_text.Text = '';
            app.nut_showleft = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_showleft.Tag = 'nut_showleft';
            app.nut_showleft.Text = 'show left PA';
            app.nut_showright = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_showright.Tag = 'nut_showright';
            app.nut_showright.Text = 'show right PA';
            app.nut_shownose = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_shownose.Tag = 'nut_shownose';
            app.nut_shownose.Text = 'show nasion';
            app.nut_load_fiducials = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_load_fiducials.Tag = 'nut_load_fiducials';
            app.nut_load_fiducials.Text = 'Import fiducials...';
            app.nut_loadHeadshape = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_loadHeadshape.Tag = 'nut_loadHeadshape';
            app.nut_loadHeadshape.Text = 'Load Digitized Headshape...';
            app.text_orientation = uilabel(app.nut_CoregistrationTool);
            app.text_orientation.Tag = 'text_orientation';
            app.text_orientation.Text = 'Orientation:';
            app.nut_load_image = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_load_image.Tag = 'nut_load_image';
            app.nut_load_image.Text = 'Select MRI...';
            app.nut_genmesh = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_genmesh.Tag = 'nut_genmesh';
            app.nut_genmesh.Text = 'Render Head Surface';
            app.nut_verify_coregistry_in_3d = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_verify_coregistry_in_3d.Tag = 'nut_verify_coregistry_in_3d';
            app.nut_verify_coregistry_in_3d.Enable = 'off';
            app.nut_verify_coregistry_in_3d.Text = 'Verify Coregistry in 3D';
            app.nut_show_fiducials_3d = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_show_fiducials_3d.Tag = 'nut_show_fiducials_3d';
            app.nut_show_fiducials_3d.Text = 'Show Fiducials in 3D';
            app.nut_edit_fiducials_3d = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_edit_fiducials_3d.Tag = 'nut_edit_fiducials_3d';
            app.nut_edit_fiducials_3d.Enable = 'off';
            app.nut_edit_fiducials_3d.Visible = 'off';
            app.nut_edit_fiducials_3d.Text = 'Edit Fiducials in 3D';
            app.nut_adjust_coreg = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_adjust_coreg.Tag = 'nut_adjust_coreg';
            app.nut_adjust_coreg.Text = 'More Complex Coregistry';
            app.nut_orientation_menu = uidropdown(app.nut_CoregistrationTool);
            app.nut_orientation_menu.Items = {'Neurological (Left on Left)', 'Radiological (Left on Right)'};
            app.nut_orientation_menu.ValueChangedFcn = createCallbackFcn(app, @nut_orientation_menu_Callback, true);
            app.nut_orientation_menu.Tag = 'nut_orientation_menu';
            app.nut_orientation_menu.Value = 'Neurological (Left on Left)';
            app.nut_ImageNameText = uilabel(app.nut_CoregistrationTool);
            app.nut_ImageNameText.Tag = 'nut_ImageNameText';
            app.nut_ImageNameText.Text = '';
            app.nut_done_coreg = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_done_coreg.ButtonPushedFcn = createCallbackFcn(app, @nut_done_coreg_Callback, true);
            app.nut_done_coreg.Tag = 'nut_done_coreg';
            app.nut_done_coreg.Text = 'Done';
            app.text6 = uilabel(app.nut_CoregistrationTool);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Advanced Coregistry';
            app.nut_coreg_firstpass = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_coreg_firstpass.Tag = 'nut_coreg_firstpass';
            app.nut_coreg_firstpass.Text = 'First Pass Coregistry';
            app.nut_lsc = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_lsc.Tag = 'nut_lsc';
            app.nut_lsc.Text = 'set sphere ctr';
            app.nut_lsc_text = uilabel(app.nut_CoregistrationTool);
            app.nut_lsc_text.Tag = 'nut_lsc_text';
            app.nut_lsc_text.Text = '';
            app.nut_showlsc = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_showlsc.Tag = 'nut_showlsc';
            app.nut_showlsc.Text = 'show sphere ctr';
            app.nut_normMRI = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_normMRI.ButtonPushedFcn = createCallbackFcn(app, @nut_normMRI_Callback, true);
            app.nut_normMRI.Tag = 'nut_normMRI';
            app.nut_normMRI.Text = 'Select Normed MRI...';
            app.nut_normimage_nametext = uilabel(app.nut_CoregistrationTool);
            app.nut_normimage_nametext.Tag = 'nut_normimage_nametext';
            app.nut_normimage_nametext.Text = '';
            app.nut_renderedbrain = uibutton(app.nut_CoregistrationTool, 'push');
            app.nut_renderedbrain.ButtonPushedFcn = createCallbackFcn(app, @nut_renderedbrain_Callback, true);
            app.nut_renderedbrain.Tag = 'nut_renderedbrain';
            app.nut_renderedbrain.Text = 'Select Rendered Brain...';
            app.nut_renderedbrain_nametext = uilabel(app.nut_CoregistrationTool);
            app.nut_renderedbrain_nametext.Tag = 'nut_renderedbrain_nametext';
            app.nut_renderedbrain_nametext.Text = '';
            % Show the figure after all components are created
            app.nut_CoregistrationTool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nut_CoregistrationTool_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.nut_CoregistrationTool)

                % Execute the startup function
                runStartupFcn(app, @(app)nut_CoregistrationTool_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.nut_CoregistrationTool)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nut_CoregistrationTool)
        end
    end
end